classdef headset_viewer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HeadsetViewerUIFigure  matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        EGI129Tab              matlab.ui.container.Tab
        EGI129Axes             matlab.ui.control.UIAxes
        EGI129TextArea         matlab.ui.control.TextArea
        DSI24Tab               matlab.ui.container.Tab
        DSI24Axes              matlab.ui.control.UIAxes
        DSI24TextArea          matlab.ui.control.TextArea
    end


    properties (Access = public)
        main_application; % link to EEGsonic_app
    end

    properties (Access = private)
        information; 
    end

    methods (Access = private)
    
        function update_gui(app)
            %% EGI129 Panel 
            egi129 = app.information.headset.egi129;
            
            % Text Area
            number_channels_string = "Number channels: " + num2str(egi129.number_channels);
            sampling_rate_string = "Sampling rate (Hz): " + num2str(egi129.sampling_rate);
            egi129_specs = number_channels_string + newline + sampling_rate_string;
            app.EGI129TextArea.Value = egi129_specs;
            
            % Headset Plotting
            topoplot([],egi129.channels_location,'style','blank','electrodes','labelpoint','chaninfo',egi129.channels_info);
            assignin('base','test',test);
            %% DSI24 Panel
            dsi24 = app.information.headset.dsi24;
            
            % Text Area
            number_channels_string = "Number channels: " + num2str(dsi24.number_channels);
            sampling_rate_string = "Sampling rate (Hz): " + num2str(dsi24.sampling_rate);
            dsi24_specs = number_channels_string + newline + sampling_rate_string;
            app.DSI24TextArea.Value = dsi24_specs;
            
            % Headset Plotting
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup
            base_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
            
            % Setting up Variables
            app_data = load(strcat(base_directory,'/../data/app_data'));
            app.information = app_data.information;        
            
            % Update the GUI
            update_gui(app);
        end

        % Close request function: HeadsetViewerUIFigure
        function HeadsetViewerUIFigureCloseRequest(app, event)
            app.main_application.is_headset_viewer_running = 0;
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HeadsetViewerUIFigure = uifigure;
            app.HeadsetViewerUIFigure.Name = 'Headset Viewer';
            app.HeadsetViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @HeadsetViewerUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.HeadsetViewerUIFigure);
            app.EGI129Tab = uitab(app.TabGroup);
            app.EGI129Tab.Title = 'EGI-129';
            app.EGI129Axes = uiaxes(app.EGI129Tab);
            title(app.EGI129Axes, 'EGI129')
            xlabel(app.EGI129Axes, 'X')
            ylabel(app.EGI129Axes, 'Y')
            app.EGI129TextArea = uitextarea(app.EGI129Tab);
            app.EGI129TextArea.Editable = 'off';
            app.DSI24Tab = uitab(app.TabGroup);
            app.DSI24Tab.Title = 'DSI-24';
            app.DSI24Axes = uiaxes(app.DSI24Tab);
            title(app.DSI24Axes, 'DSI24')
            xlabel(app.DSI24Axes, 'X')
            ylabel(app.DSI24Axes, 'Y')
            app.DSI24TextArea = uitextarea(app.DSI24Tab);
            app.DSI24TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = headset_viewer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HeadsetViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HeadsetViewerUIFigure)
        end
    end
end