classdef replayer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Replayer          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        TabGroup          matlab.ui.container.TabGroup
        ReplayerTab       matlab.ui.container.Tab
        DataSliderLabel   matlab.ui.control.Label
        DataSlider        matlab.ui.control.Slider
        StartSliderLabel  matlab.ui.control.Label
        StartSlider       matlab.ui.control.Slider
        StopSliderLabel   matlab.ui.control.Label
        StopSlider        matlab.ui.control.Slider
        InformationTab    matlab.ui.container.Tab
        PauseButton       matlab.ui.control.StateButton
        Switch            matlab.ui.control.Switch
    end


    properties (Access = public)
        main_application; % link to EEGsonic_app
    end

    properties (Access = private)
        % Structure containing paths
        play;
        parameters;
        information;
        loading_directory;
        data_directory;
        parameters_directory;
        start_index;
        stop_index;
        current_index;
        is_loop;
        number_index;
    end

    methods (Access = private)
        
        function number_index = get_number_index(app,data_directory)
            file_list = dir(data_directory);
            number_index = 0;
            for i = 1:length(file_list)
                value = split(file_list(i).name,".");
                value = value(1);
                try
                    value = str2num(cell2mat(value(1)));
                    if(value > number_index)
                       number_index = value; 
                    end
                catch
                    
                end
            end
        end
        
        function set_gui(app)
            app.DataSlider.Limits = [0,app.number_index];
            app.DataSlider.Value = app.start_index;
            app.StartSlider.Limits = [0,app.number_index];
            app.StartSlider.Value = app.start_index;
            app.StopSlider.Limits = [0,app.number_index];
            app.StopSlider.Value = app.stop_index;
        end
        
    end

    methods (Access = public)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup Base Directory
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
          
            % Setting up Variables
            app_data = load(strcat(base_directory,'/../data/app_data.mat'));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.information.base_directory = base_directory;
            app.loading_directory = app.parameters.general.replay.replay_directory;
            app.data_directory = strcat(app.loading_directory,filesep,"data");
            app.parameters_directory = strcat(app.loading_directory,filesep,"parameters");
            
            % Load and set the information and parameters to be equal to those of the previous recording
            app_data = load(strcat(app.parameters_directory,'/app_data.mat'));
            app_data = app_data.app_data;
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            
            % Setup the GUI
            app.number_index = get_number_index(app,app.data_directory);
            app.start_index = 0;
            app.stop_index = app.number_index;
            app.current_index = 0;
            app.is_loop = 0;
            set_gui(app);
            
        end

        % Close request function: Replayer
        function ReplayerCloseRequest(app, event)
            app.main_application.is_replayer_running = 0;
            delete(app);
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if(value == 1)
                app.play = 1;
                app.PauseButton.Text = "Play";
            else
                app.play = 0;
                app.PauseButton.Text = "Pause";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(strcmp(value,"Loop On"))
               app.is_loop = 1; 
            else
               app.is_loop = 0;
            end
            disp(app.is_loop)
        end

        % Value changing function: DataSlider
        function DataSliderValueChanging(app, event)
            changingValue = event.Value;
            
            if(changingValue < app.start_index )
               app.DataSlider.Value = app.start_index; 
            elseif(changingValue > app.stop_index)
                app.DataSlider.Value = app.stop_index;
            else
                app.DataSlider.Value = changingValue;
            end
            
            app.current_index = app.DataSlider.Value;
        end

        % Value changing function: StartSlider
        function StartSliderValueChanging(app, event)
            changingValue = event.Value;
            if(changingValue > app.stop_index)
               app.StartSlider.Value = app.stop_index;
            elseif(changingValue > app.current_index)
                app.current_index = changingValue;
                app.DataSlider.Value = changingValue;
                app.StartSlider.Value = changingValue;
            else
                app.StartSlider.Value = changingValue;
            end
            app.start_index = app.StartSlider.Value;
        end

        % Value changing function: StopSlider
        function StopSliderValueChanging(app, event)
            changingValue = event.Value;
            if(changingValue < app.start_index)
               app.StopSlider.Value = app.start_index;
            elseif(changingValue < app.current_index)
                app.current_index = changingValue;
                app.DataSlider.Value = changingValue;
                app.StopSlider.Value = changingValue;
            else
                app.StopSlider.Value = changingValue;
            end
            app.stop_index = app.StopSlider.Value;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Replayer = uifigure;
            app.Replayer.Name = 'Replayer';
            app.Replayer.CloseRequestFcn = createCallbackFcn(app, @ReplayerCloseRequest, true);
            app.Menu = uimenu(app.Replayer);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.Replayer);
            app.ReplayerTab = uitab(app.TabGroup);
            app.ReplayerTab.Title = 'Replayer';
            app.DataSliderLabel = uilabel(app.ReplayerTab);
            app.DataSliderLabel.Text = 'Data';
            app.DataSlider = uislider(app.ReplayerTab);
            app.DataSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.DataSlider.ValueChangingFcn = createCallbackFcn(app, @DataSliderValueChanging, true);
            app.StartSliderLabel = uilabel(app.ReplayerTab);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ReplayerTab);
            app.StartSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.StartSlider.ValueChangingFcn = createCallbackFcn(app, @StartSliderValueChanging, true);
            app.StopSliderLabel = uilabel(app.ReplayerTab);
            app.StopSliderLabel.Text = 'Stop';
            app.StopSlider = uislider(app.ReplayerTab);
            app.StopSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.StopSlider.ValueChangingFcn = createCallbackFcn(app, @StopSliderValueChanging, true);
            app.StopSlider.Value = 100;
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.PauseButton = uibutton(app.Replayer, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.Switch = uiswitch(app.Replayer, 'slider');
            app.Switch.Items = {'Loop Off', 'Loop On'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Loop Off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = replayer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Replayer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Replayer)
        end
    end
end